classdef Semaforo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Panel               matlab.ui.container.Panel
        Coches              matlab.ui.control.Label
        nPersonasText       matlab.ui.control.Label
        Personas            matlab.ui.control.Label
        nCochesText         matlab.ui.control.Label
        CochesLampLabel     matlab.ui.control.Label
        CochesSem           matlab.ui.control.Lamp
        PeatonesLampLabel   matlab.ui.control.Label
        PeatonesSem         matlab.ui.control.Lamp
        img                 matlab.ui.control.Image
        CargarImagenButton  matlab.ui.control.Button
        DetecteccinButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        Imagen % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarImagenButton
        function CargarImagenButtonPushed(app, event)
           
            [filename, pathname]= uigetfile({'*.jpg;*.tif;*.png;*.gif','All Image Files'; '*.*','All Files' },'Choose an image file ...');
            
            if isequal(filename,0)
                warndlg('File not selected');
                return
            end
            
            image=imread(strcat(pathname,filename));
            
            if not(size(image,3)==3)
                errordlg('The image must be colored');
                return
            end
            app.Imagen = image;
            app.img.ImageSource = image;
        end

        % Button pushed function: DetecteccinButton
        function DetecteccinButtonPushed(app, event)
           imageSource = app.Imagen;
           
           elementos = Deteccion(imageSource);
           condicionDistancia = Distancia(imageSource);
           estadoSemaforo = colorSemaforo(elementos(1,:),elementos(2,:),condicionDistancia);
           
           if estadoSemaforo == true
               app.CochesSem.Color = 'green';
               app.PeatonesSem.Color = 'red';
           else
               app.CochesSem.Color = 'red';
               app.PeatonesSem.Color = 'green';
           end
           
           app.nCochesText.Text = num2str(elementos(1,:));
           app.nPersonasText.Text = num2str(elementos(2,:));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Coches = uilabel(app.Panel);
            app.Coches.Text = 'Coches en la imagen';
            app.nPersonasText = uilabel(app.Panel);
            app.nPersonasText.Text = '...';
            app.Personas = uilabel(app.Panel);
            app.Personas.Text = 'Peatones en la imagen';
            app.nCochesText = uilabel(app.Panel);
            app.nCochesText.Text = '...';
            app.CochesLampLabel = uilabel(app.Panel);
            app.CochesLampLabel.Text = 'Coches';
            app.CochesSem = uilamp(app.Panel);
            app.PeatonesLampLabel = uilabel(app.Panel);
            app.PeatonesLampLabel.Text = 'Peatones';
            app.PeatonesSem = uilamp(app.Panel);
            app.img = uiimage(app.UIFigure);
            app.CargarImagenButton = uibutton(app.UIFigure, 'push');
            app.CargarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarImagenButtonPushed, true);
            app.CargarImagenButton.Text = 'Cargar Imagen';
            app.DetecteccinButton = uibutton(app.UIFigure, 'push');
            app.DetecteccinButton.ButtonPushedFcn = createCallbackFcn(app, @DetecteccinButtonPushed, true);
            app.DetecteccinButton.Text = {'Detectección'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Semaforo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end